classdef Zixiu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LoadtiforcxdButton           matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        UIAxes                       matlab.ui.control.UIAxes
        ImageControlsPanel           matlab.ui.container.Panel
        MinSliderLabel               matlab.ui.control.Label
        MinSlider                    matlab.ui.control.Slider
        MaxSliderLabel               matlab.ui.control.Label
        MaxSlider                    matlab.ui.control.Slider
        Gauge                        matlab.ui.control.LinearGauge
        FrameDisplayedLabel          matlab.ui.control.Label
        ImagetoDisplayDropDownLabel  matlab.ui.control.Label
        ImagetoDisplayDropDown       matlab.ui.control.DropDown
        LoopImagesButton             matlab.ui.control.Button
        GoButton                     matlab.ui.control.Button
        SelectFrameLabel             matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        BalancingImagesPanel         matlab.ui.container.Panel
        BalanceImagesButton          matlab.ui.control.Button
        BackgroundZoneLabel          matlab.ui.control.Label
        ShowButton                   matlab.ui.control.Button
        XEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        YEditField                   matlab.ui.control.NumericEditField
        REditFieldLabel              matlab.ui.control.Label
        REditField                   matlab.ui.control.NumericEditField
        SaveBalancedImagesButton     matlab.ui.control.Button
        AnalyzeanROIPanel            matlab.ui.container.Panel
        FvsTimeButton                matlab.ui.control.Button
        ROIZoneLabel                 matlab.ui.control.Label
        ShowButton_2                 matlab.ui.control.Button
        XEditField_2Label            matlab.ui.control.Label
        XEditField_2                 matlab.ui.control.NumericEditField
        YEditField_2Label            matlab.ui.control.Label
        YEditField_2                 matlab.ui.control.NumericEditField
        REditField_2Label            matlab.ui.control.Label
        REditField_2                 matlab.ui.control.NumericEditField
        roiZoomButton                matlab.ui.control.StateButton
        UIAxes2                      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        dim % image size
        imstack % image stack
        imdisp % dummy image stack to display ROIs
        imorig % will remain unchanged
        frameVal %current display frame
        bxval =10;% border x val (roi)
        byval =10;%  y value
        brval =10;%  radius value
        maxVal = 255;
        minVal = 0;
        fname = '';
        fbalname = '';
        F %fluorescence change
        zState = 0;
        onFrame = 0; % Keep track of whether or not a specific frame is targeted
    end

    methods (Access = public)
    
        function tiffRange(app)
            m = min(app.imstack(:));
            M = max(app.imstack(:));
            app.imstack = app.imstack - m;
            app.imstack = app.imstack./(M-m);
            app.imstack = app.imstack.*255;
        end
        
    end

    methods (Access = private)
    
        function stackRead(app)
            fname = app.fname;
            info = imfinfo(fname);
            num_images = numel(info);
            A = zeros(info(1,1).Height,info(1,1).Width,num_images);
            for k = 1:num_images
                A(:,:,k) = imread(fname, k, 'Info', info);
                % ... Do something with image A ...
            end
            app.imstack = A;
        end
        
        function stackWrite(app, filename, variable)
            
                dim = app.dim;
                variable = variable./max(variable(:));
                if length(dim)==2
                    imwrite(variable, filename, 'WriteMode', 'overwrite');
                elseif length(dim)==3
                    imwrite(variable(:,:,1),filename,'WriteMode','overwrite');
                    for i=2:dim(3)
                        imwrite(variable(:,:,i),filename,'WriteMode','append');
                    end
                end
        end
        
    end


    methods (Access = private)

        % Button pushed function: LoadtiforcxdButton
        function load_im(app, event)
            [file,path] = uigetfile({'*.cxd'; '*.tif';});
            app.fname = [path file];
            %app.TextArea.Value = strcat('File: ',file, newline,'Path: ',path);
            app.TextArea.Value = ['File: ' file newline 'Path: ' path];
            if file(end) == 'f'
                app.stackRead(); % We'll change this one with balance im
            else
                app.imstack = useBF(app.fname);
            end
            app.tiffRange();
            app.imorig = app.imstack; % will remain unchanged
            app.imdisp = app.imstack; % will have ROIs drawn in 
            app.dim = size(app.imstack);
            min(app.imstack(:))
            app.Gauge.Limits = [0 app.dim(3)];
            imagesc(app.UIAxes,app.imstack(:,: ,1));
            colormap(app.UIAxes, gray);
            caxis(app.UIAxes, [app.minVal app.maxVal]);
            app.UIAxes.XLim = [1 app.dim(1)]; 
            app.UIAxes.YLim = [1 app.dim(2)];
            
            
        end

        % Button pushed function: LoopImagesButton
        function loop_im(app, event)
            if (app.dim(1)>0) % if any image loaded
                app.onFrame = 0;
                if (app.ImagetoDisplayDropDown.Value == "ROI Image")
                    for i=1:app.dim(3)
                        imagesc(app.UIAxes, app.imdisp(:,:,i));
                        caxis(app.UIAxes, [app.minVal app.maxVal]);
                        pause(.1)
                        app.Gauge.Value = i;
                    end
                end
                if (app.ImagetoDisplayDropDown.Value == "Balanced Image")
                    for i=1:app.dim(3)
                        imagesc(app.UIAxes, app.imstack(:,:,i));
                        caxis(app.UIAxes, [app.minVal app.maxVal]);
                        pause(.1)
                        app.Gauge.Value = i;
                    end
                end
                if (app.ImagetoDisplayDropDown.Value == "Orig image")
                    for i=1:app.dim(3)
                        imagesc(app.UIAxes, app.imorig(:,:,i));
                        caxis(app.UIAxes, [app.minVal app.maxVal]);
                        pause(.1)
                        app.Gauge.Value = i;
                    end
                end
            end
        end

        % Button pushed function: ShowButton
        function showBGZ(app, event)
            % Switched x and y for Zixiu
            app.byval = app.XEditField.Value; % background x value
            app.bxval = app.YEditField.Value;% backgroudn y value
            app.brval = app.REditField.Value;% background radius value
            if (app.bxval ==0)
                app.bxval =1;
            end
            if (app.byval ==0)
                app.byval = 1;
            end
            if (app.brval ==0)
                app.brval =2;
            end
            app.imdisp = app.imstack;
            app.imdisp(app.bxval:app.bxval+app.brval,app.byval-2:app.byval+2,:) = app.maxVal;
            app.imdisp(app.bxval:app.bxval+app.brval,app.byval+app.brval-2:app.byval+app.brval+2,:) = app.maxVal;
            app.imdisp(app.bxval-2:app.bxval+2,app.byval:app.byval+app.brval,:) = app.maxVal;
            app.imdisp(app.bxval+app.brval-2:app.bxval+app.brval+2,app.byval:app.byval+app.brval,:) = app.maxVal;
            if (app.onFrame)
                imagesc(app.UIAxes,app.imdisp(:,:,app.EditField.Value));
            else
                imagesc(app.UIAxes,app.imdisp(:,:,1));
            end
        end

        % Button pushed function: BalanceImagesButton
        function bal_Im(app, event)
            % Switched x and y for Zixiu
            app.byval = app.XEditField.Value; % background x value
            app.bxval = app.YEditField.Value;% backgroudn y value
            app.brval = app.REditField.Value;% background radius value
            bb = [app.bxval app.byval (app.bxval+app.brval) (app.byval+app.brval)];
            aveI = mean(app.imorig(bb(2):bb(4),bb(1):bb(3),:),1);
            aveI = mean(aveI,2);
            aveI = squeeze(aveI);
            Ioff = aveI - min(aveI(:));
            Im_balanced = zeros(app.dim);
            for i=1:app.dim(3)
                Im_balanced(:,:,i) = app.imorig(:,:,i) - Ioff(i);
            end   
            app.imstack = Im_balanced;
            
        end

        % Value changed function: MinSlider
        function Set_min(app, event)
            app.minVal = app.MinSlider.Value;
            if (app.onFrame)
                imagesc(app.UIAxes,app.imdisp(:,:,app.EditField.Value));
            else
                imagesc(app.UIAxes,app.imdisp(:,:,1));
            end
            caxis(app.UIAxes, [app.minVal app.maxVal]);
            
        end

        % Value changed function: MaxSlider
        function set_Max(app, event)
            app.maxVal = app.MaxSlider.Value;
            if (app.onFrame)
                imagesc(app.UIAxes,app.imdisp(:,:,app.EditField.Value));
            else
                imagesc(app.UIAxes,app.imdisp(:,:,1));
            end
            caxis(app.UIAxes, [app.minVal app.maxVal]);
        end

        % Button pushed function: SaveBalancedImagesButton
        function save_bal_im(app, event)
            app.fbalname = [app.fname(1:end-4) '_balanced.tif'];
            app.stackWrite(app.fbalname, app.imstack);
        end

        % Button pushed function: ShowButton_2
        function showROI(app, event)
            %witched x and y for Zixiu
            app.byval = app.XEditField_2.Value; % background x value
            app.bxval = app.YEditField_2.Value;% backgroudn y value
            app.brval = app.REditField_2.Value;% background radius value
            if (app.bxval ==0)
                app.bxval =1;
            end
            if (app.byval ==0)
                app.byval = 1;
            end
            if (app.brval ==0)
                app.brval =2;
            end
            app.imdisp = app.imstack;
            app.imdisp(app.bxval:app.bxval+app.brval,app.byval-2:app.byval+2,:) = app.maxVal;
            app.imdisp(app.bxval:app.bxval+app.brval,app.byval+app.brval-2:app.byval+app.brval+2,:) = app.maxVal;
            app.imdisp(app.bxval-2:app.bxval+2,app.byval:app.byval+app.brval,:) = app.maxVal;
            app.imdisp(app.bxval+app.brval-2:app.bxval+app.brval+2,app.byval:app.byval+app.brval,:) = app.maxVal;
            if (app.onFrame)
                imagesc(app.UIAxes,app.imdisp(:,:,app.EditField.Value));
            else
                imagesc(app.UIAxes,app.imdisp(:,:,1));
            end         
        end

        % Button pushed function: FvsTimeButton
        function getF(app, event)
            ROI = [app.bxval app.byval app.bxval+app.brval app.byval+app.brval];
            fim = app.imstack; % fluorescence image (already balanced by balance_Im)
            
            f0 = fim(ROI(1):ROI(3),ROI(2):ROI(4),1);
            f0m = mean(f0(:));
            
            dim = app.dim;
            data = zeros(dim(3),1);
            data(1) = f0m;
            
            for i=2:dim(3)
                %froi = fim(ROI(2):ROI(4),ROI(1):ROI(3),i);
                froi = fim(ROI(1):ROI(3),ROI(2):ROI(4),i);
                data(i) = mean(froi(:));
            end
            
            app.F = data./f0m;  
            'ROI delta F vs time'
            app.F
            upLim = max(app.F(:));
            if (upLim < 1.5) upLim = 1.5; else upLim = ceil(upLim); end
            plot(app.UIAxes2,app.F');
            app.UIAxes2.XLim = [1 dim(3)];
            app.UIAxes2.YLim = [0.5 upLim];
        end

        % Value changed function: roiZoomButton
        function roiZoom(app, event)
            if app.zState==0
                app.UIAxes.XLim = [app.byval-100 app.byval+app.brval+100]; 
                app.UIAxes.YLim = [app.bxval-100 app.bxval+app.brval+100];
                app.zState=1;
            else
                app.UIAxes.XLim = [1 app.dim(1)]; 
                app.UIAxes.YLim = [1 app.dim(2)];
                app.zState=0;
            end
        end

        % Button pushed function: GoButton
        function go2frame(app, event)
            fnum = app.EditField.Value;
            if fnum > app.dim(3)
            else
                imagesc(app.UIAxes, app.imdisp(:,:,fnum));
                app.onFrame = 1;
                app.Gauge.Value = fnum;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadtiforcxdButton = uibutton(app.UIFigure, 'push');
            app.LoadtiforcxdButton.ButtonPushedFcn = createCallbackFcn(app, @load_im, true);
            app.LoadtiforcxdButton.Text = 'Load  .tif or .cxd';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.ImageControlsPanel = uipanel(app.UIFigure);
            app.ImageControlsPanel.Title = 'Image Controls';
            app.MinSliderLabel = uilabel(app.ImageControlsPanel);
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider = uislider(app.ImageControlsPanel);
            app.MinSlider.Limits = [0 255];
            app.MinSlider.MajorTicks = [0 50 100 150 200 255];
            app.MinSlider.MajorTickLabels = {'0', '', '', '', '', '255'};
            app.MinSlider.ValueChangedFcn = createCallbackFcn(app, @Set_min, true);
            app.MaxSliderLabel = uilabel(app.ImageControlsPanel);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider = uislider(app.ImageControlsPanel);
            app.MaxSlider.Limits = [0 255];
            app.MaxSlider.MajorTicks = [0 50 100 150 200 255];
            app.MaxSlider.MajorTickLabels = {'0', '', '', '', '', '255'};
            app.MaxSlider.ValueChangedFcn = createCallbackFcn(app, @set_Max, true);
            app.MaxSlider.Value = 255;
            app.Gauge = uigauge(app.ImageControlsPanel, 'linear');
            app.Gauge.Limits = [0 40];
            app.Gauge.MinorTicks = [];
            app.Gauge.Value = 1;
            app.FrameDisplayedLabel = uilabel(app.ImageControlsPanel);
            app.FrameDisplayedLabel.Text = 'Frame Displayed';
            app.ImagetoDisplayDropDownLabel = uilabel(app.ImageControlsPanel);
            app.ImagetoDisplayDropDownLabel.Text = 'Image to Display';
            app.ImagetoDisplayDropDown = uidropdown(app.ImageControlsPanel);
            app.ImagetoDisplayDropDown.Items = {'Orig image', 'ROI Image', 'Balanced Image'};
            app.ImagetoDisplayDropDown.Value = 'Orig image';
            app.LoopImagesButton = uibutton(app.ImageControlsPanel, 'push');
            app.LoopImagesButton.ButtonPushedFcn = createCallbackFcn(app, @loop_im, true);
            app.LoopImagesButton.Text = 'Loop Images';
            app.GoButton = uibutton(app.ImageControlsPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @go2frame, true);
            app.GoButton.Text = 'Go';
            app.SelectFrameLabel = uilabel(app.ImageControlsPanel);
            app.SelectFrameLabel.Text = 'Select Frame';
            app.EditField = uieditfield(app.ImageControlsPanel, 'numeric');
            app.BalancingImagesPanel = uipanel(app.UIFigure);
            app.BalancingImagesPanel.Title = 'Balancing Images';
            app.BalanceImagesButton = uibutton(app.BalancingImagesPanel, 'push');
            app.BalanceImagesButton.ButtonPushedFcn = createCallbackFcn(app, @bal_Im, true);
            app.BalanceImagesButton.Text = 'Balance Images';
            app.BackgroundZoneLabel = uilabel(app.BalancingImagesPanel);
            app.BackgroundZoneLabel.Text = 'Background Zone';
            app.ShowButton = uibutton(app.BalancingImagesPanel, 'push');
            app.ShowButton.ButtonPushedFcn = createCallbackFcn(app, @showBGZ, true);
            app.ShowButton.Text = 'Show';
            app.XEditFieldLabel = uilabel(app.BalancingImagesPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.BalancingImagesPanel, 'numeric');
            app.XEditField.Value = 10;
            app.YEditFieldLabel = uilabel(app.BalancingImagesPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.BalancingImagesPanel, 'numeric');
            app.YEditField.Value = 10;
            app.REditFieldLabel = uilabel(app.BalancingImagesPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.BalancingImagesPanel, 'numeric');
            app.REditField.Value = 10;
            app.SaveBalancedImagesButton = uibutton(app.BalancingImagesPanel, 'push');
            app.SaveBalancedImagesButton.ButtonPushedFcn = createCallbackFcn(app, @save_bal_im, true);
            app.SaveBalancedImagesButton.Text = 'Save Balanced Images';
            app.AnalyzeanROIPanel = uipanel(app.UIFigure);
            app.AnalyzeanROIPanel.Title = 'Analyze an ROI';
            app.FvsTimeButton = uibutton(app.AnalyzeanROIPanel, 'push');
            app.FvsTimeButton.ButtonPushedFcn = createCallbackFcn(app, @getF, true);
            app.FvsTimeButton.Text = ' F vs Time';
            app.ROIZoneLabel = uilabel(app.AnalyzeanROIPanel);
            app.ROIZoneLabel.Text = 'ROI Zone';
            app.ShowButton_2 = uibutton(app.AnalyzeanROIPanel, 'push');
            app.ShowButton_2.ButtonPushedFcn = createCallbackFcn(app, @showROI, true);
            app.ShowButton_2.Text = 'Show';
            app.XEditField_2Label = uilabel(app.AnalyzeanROIPanel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.AnalyzeanROIPanel, 'numeric');
            app.XEditField_2.Value = 10;
            app.YEditField_2Label = uilabel(app.AnalyzeanROIPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.AnalyzeanROIPanel, 'numeric');
            app.YEditField_2.Value = 10;
            app.REditField_2Label = uilabel(app.AnalyzeanROIPanel);
            app.REditField_2Label.Text = 'R';
            app.REditField_2 = uieditfield(app.AnalyzeanROIPanel, 'numeric');
            app.REditField_2.Value = 10;
            app.roiZoomButton = uibutton(app.AnalyzeanROIPanel, 'state');
            app.roiZoomButton.ValueChangedFcn = createCallbackFcn(app, @roiZoom, true);
            app.roiZoomButton.Text = 'roiZoom';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Fluorescence Change for ROI')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Delta F')
        end
    end

    methods (Access = public)

        % Construct app
        function app = Zixiu

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end